classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Label                matlab.ui.control.Label
        Image_3              matlab.ui.control.Image
        Image_2              matlab.ui.control.Image
        LoadFileButton       matlab.ui.control.Button
        ResultImageLabel     matlab.ui.control.Label
        DetectedShadowLabel  matlab.ui.control.Label
        OriginalImageLabel   matlab.ui.control.Label
        Group2YiningJiangZhaojunNiLanxuanZhangLabel  matlab.ui.control.Label
        CS270Fall2024Project5ShadowDetectionandRemovalLabel  matlab.ui.control.Label
        Image                matlab.ui.control.Image
    end

    
    properties (Access = private)
        ClickPosition = [];
    end
    
    methods (Access = private)
        
        function enableSeedSelect(app)
            app.UIFigure.ButtonDownFcn = @(src, event) UIFigureButtonDown(app, event);
        end

        function disableSeedSelect(app)
            app.UIFigure.ButtonDownFcn = '';
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file,path] = uigetfile({'../dataset/shadow/*.jpg'},'Select an Image');
            if isequal(file,0)
                disp('User selected cancel.');
            else
                imgPath = fullfile(path,file);
                originalImage = imread(imgPath);
                app.Image.ImageSource = imgPath;
                innerPos = app.Image.InnerPosition;
                app.enableSeedSelect;
                innerPosStr = [num2str(innerPos(1)),' ', num2str(innerPos(2)), ' ',num2str(innerPos(3)),' ', num2str(innerPos(4))];
                app.Label.Text = innerPosStr;
                %disp(app.Image.InnerPosition);
                shadowMask = detect_shadow(originalImage);
                app.Image_2.ImageSource = shadowMask;
                
            end
        end

        % Callback function: not associated with a component
        function ImageClicked(app, event)

        end

        % Callback function: not associated with a component
        function UIFigureKeyRelease(app, event)

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, 'q') || strcmp(key, 'Q')
                delete(app);
            else
            end
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            app.ClickPosition = app.UIFigure.CurrentPoint;
            app.Label.Text = num2str(app.ClickPosition);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Image = uiimage(app.UIFigure);
            app.CS270Fall2024Project5ShadowDetectionandRemovalLabel = uilabel(app.UIFigure);
            app.CS270Fall2024Project5ShadowDetectionandRemovalLabel.Text = 'CS270 Fall 2024 Project 5: Shadow Detection and Removal';
            app.Group2YiningJiangZhaojunNiLanxuanZhangLabel = uilabel(app.UIFigure);
            app.Group2YiningJiangZhaojunNiLanxuanZhangLabel.Text = 'Group #2: Yining Jiang, Zhaojun Ni, Lanxuan Zhang';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.DetectedShadowLabel = uilabel(app.UIFigure);
            app.DetectedShadowLabel.Text = 'Detected Shadow';
            app.ResultImageLabel = uilabel(app.UIFigure);
            app.ResultImageLabel.Text = 'Result Image';
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_3 = uiimage(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end