classdef shadowmover < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        offsetSpinner        matlab.ui.control.Spinner
        offsetSpinnerLabel   matlab.ui.control.Label
        boundaryradius_2     matlab.ui.control.Spinner
        erode_radiusLabel_2  matlab.ui.control.Label
        boundaryradius       matlab.ui.control.Spinner
        erode_radiusLabel    matlab.ui.control.Label
        boundaryExtractBtn   matlab.ui.control.Button
        sigmaSpinner         matlab.ui.control.Spinner
        sigmaSpinnerLabel    matlab.ui.control.Label
        removalResultBtn     matlab.ui.control.Button
        regionGrowradius     matlab.ui.control.Spinner
        dilate_radiusLabel   matlab.ui.control.Label
        regionGrowBtn        matlab.ui.control.Button
        seedradius           matlab.ui.control.Spinner
        open_radiusLabel     matlab.ui.control.Label
        seedSelectBtn        matlab.ui.control.Button
        shadowDetectBtn      matlab.ui.control.Button
        shadowtheshold       matlab.ui.control.Spinner
        light_thesholdLabel  matlab.ui.control.Label
        ChooseFileButton     matlab.ui.control.Button
        Group2YiningJiangZhaojunNiLanxuanZhangLabel  matlab.ui.control.Label
        CS270Fall2024Project5ShadowDetectionandRemovalLabel  matlab.ui.control.Label
        UIAxes6              matlab.ui.control.UIAxes
        UIAxes5              matlab.ui.control.UIAxes
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes1              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img;
        shadow_mask;
        open_mask;
        x;
        y;
        final_mask;
        boundary_region;
        smooth_region;
        corrected_img;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            clc;
            [file,path] = uigetfile({'../dataset/shadow/*.jpg'},'Select an Image');
            if isequal(file,0)
                disp('User selected cancel.');
            else
                imgPath = fullfile(path,file);
                app.img = imread(imgPath);
                imshow(app.img,'Parent',app.UIAxes1);
            end
        end

        % Button pushed function: shadowDetectBtn
        function shadowDetectBtnPushed(app, event)
            app.shadow_mask = detect_shadow(app.img,app.shadowtheshold.Value);
            imshow(app.shadow_mask,'Parent',app.UIAxes2);

        end

        % Button pushed function: seedSelectBtn
        function seedSelectBtnButtonPushed(app, event)

            se1 = strel('disk', abs(app.seedradius.Value));
            if (app.seedradius.Value > 0)
                app.open_mask = imopen(app.shadow_mask, se1); 
            else
                app.open_mask = imclose(app.shadow_mask, se1); 
            end
            imshow(app.open_mask,[]);
            hold on;
            imshow(app.open_mask,'Parent',app.UIAxes3);
            %hold on;
            [app.x, app.y] = ginput(); 
            app.x = round(app.x);      
            app.y = round(app.y);
            [h, w] = size(app.open_mask);
            valid_points = (app.x > 0 & app.x <= w) & (app.y > 0 & app.y <= h);
            app.x = app.x(valid_points);
            app.y = app.y(valid_points);
            hold(app.UIAxes3,'on');
            plot(app.UIAxes3,app.x, app.y, 'r*', 'MarkerSize', 10);
           
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)

        end

        % Button pushed function: regionGrowBtn
        function regionGrowBtnButtonPushed(app, event)
            app.final_mask = region_growing(app.open_mask,app.regionGrowradius.Value,app.x,app.y);
            imshow(app.final_mask,'Parent',app.UIAxes4);
        end

        % Button pushed function: boundaryExtractBtn
        function boundaryExtractBtnButtonPushed(app, event)
            [app.boundary_region,app.smooth_region] = extract_boundary(app.final_mask,app.boundaryradius.Value,app.boundaryradius_2.Value);
            imshow(app.smooth_region,'Parent',app.UIAxes5);
        end

        % Button pushed function: removalResultBtn
        function removalResultBtnButtonPushed(app, event)
            app.corrected_img = shadow_removal(app.img,app.boundary_region,app.smooth_region,app.final_mask,app.sigmaSpinner.Value,app.offsetSpinner.Value);
            imshow(app.corrected_img,'Parent',app.UIAxes6);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Original Image')
            app.UIAxes1.XTick = [];
            app.UIAxes1.XTickLabel = '';
            app.UIAxes1.YTick = [];
            app.UIAxes1.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Shadow Detection')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = '';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Seed Selection')
            app.UIAxes3.XTick = [];
            app.UIAxes3.XTickLabel = '';
            app.UIAxes3.YTick = [];
            app.UIAxes3.YTickLabel = '';
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Region Growing')
            app.UIAxes4.XTick = [];
            app.UIAxes4.XTickLabel = '';
            app.UIAxes4.YTick = [];
            app.UIAxes4.YTickLabel = '';
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Boundary Extraction')
            app.UIAxes5.XTick = [];
            app.UIAxes5.XTickLabel = '';
            app.UIAxes5.YTick = [];
            app.UIAxes5.YTickLabel = '';
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Removal Result')
            app.UIAxes6.XTick = [];
            app.UIAxes6.XTickLabel = '';
            app.UIAxes6.YTick = [];
            app.UIAxes6.YTickLabel = '';
            app.CS270Fall2024Project5ShadowDetectionandRemovalLabel = uilabel(app.UIFigure);
            app.CS270Fall2024Project5ShadowDetectionandRemovalLabel.Text = 'CS270 Fall 2024 Project 5: Shadow Detection and Removal';
            app.Group2YiningJiangZhaojunNiLanxuanZhangLabel = uilabel(app.UIFigure);
            app.Group2YiningJiangZhaojunNiLanxuanZhangLabel.Text = 'Group #2: Yining Jiang, Zhaojun Ni, Lanxuan Zhang';
            app.ChooseFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.light_thesholdLabel = uilabel(app.UIFigure);
            app.light_thesholdLabel.Text = 'light_theshold';
            app.shadowtheshold = uispinner(app.UIFigure);
            app.shadowtheshold.Step = 0.2;
            app.shadowtheshold.Value = 3;
            app.shadowDetectBtn = uibutton(app.UIFigure, 'push');
            app.shadowDetectBtn.ButtonPushedFcn = createCallbackFcn(app, @shadowDetectBtnPushed, true);
            app.shadowDetectBtn.Text = 'OK';
            app.seedSelectBtn = uibutton(app.UIFigure, 'push');
            app.seedSelectBtn.ButtonPushedFcn = createCallbackFcn(app, @seedSelectBtnButtonPushed, true);
            app.seedSelectBtn.Text = 'OK';
            app.open_radiusLabel = uilabel(app.UIFigure);
            app.open_radiusLabel.Text = 'open_radius';
            app.seedradius = uispinner(app.UIFigure);
            app.seedradius.Value = 3;
            app.regionGrowBtn = uibutton(app.UIFigure, 'push');
            app.regionGrowBtn.ButtonPushedFcn = createCallbackFcn(app, @regionGrowBtnButtonPushed, true);
            app.regionGrowBtn.Text = 'OK';
            app.dilate_radiusLabel = uilabel(app.UIFigure);
            app.dilate_radiusLabel.Text = 'dilate_radius';
            app.regionGrowradius = uispinner(app.UIFigure);
            app.regionGrowradius.Limits = [0 Inf];
            app.regionGrowradius.Value = 1;
            app.removalResultBtn = uibutton(app.UIFigure, 'push');
            app.removalResultBtn.ButtonPushedFcn = createCallbackFcn(app, @removalResultBtnButtonPushed, true);
            app.removalResultBtn.Text = 'OK';
            app.sigmaSpinnerLabel = uilabel(app.UIFigure);
            app.sigmaSpinnerLabel.Text = 'sigma';
            app.sigmaSpinner = uispinner(app.UIFigure);
            app.sigmaSpinner.Limits = [0 Inf];
            app.sigmaSpinner.Value = 2;
            app.boundaryExtractBtn = uibutton(app.UIFigure, 'push');
            app.boundaryExtractBtn.ButtonPushedFcn = createCallbackFcn(app, @boundaryExtractBtnButtonPushed, true);
            app.boundaryExtractBtn.Text = 'OK';
            app.erode_radiusLabel = uilabel(app.UIFigure);
            app.erode_radiusLabel.Text = 'e_r';
            app.boundaryradius = uispinner(app.UIFigure);
            app.boundaryradius.Limits = [0 Inf];
            app.boundaryradius.Value = 6;
            app.erode_radiusLabel_2 = uilabel(app.UIFigure);
            app.erode_radiusLabel_2.Text = 'd_r';
            app.boundaryradius_2 = uispinner(app.UIFigure);
            app.boundaryradius_2.Limits = [0 Inf];
            app.boundaryradius_2.Value = 1;
            app.offsetSpinnerLabel = uilabel(app.UIFigure);
            app.offsetSpinnerLabel.Text = 'offset';
            app.offsetSpinner = uispinner(app.UIFigure);
            app.offsetSpinner.Step = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shadowmover

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end